classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ModeMenu                   matlab.ui.container.Menu
        CalibrationMenu            matlab.ui.container.Menu
        sensorsMenu                matlab.ui.container.Menu
        pumpsMenu                  matlab.ui.container.Menu
        valvesMenu                 matlab.ui.container.Menu
        ControlMenu                matlab.ui.container.Menu
        SensorPanel                matlab.ui.container.Panel
        StopButton                 matlab.ui.control.Button
        confirmButton_9            matlab.ui.control.Button
        confirmButton_8            matlab.ui.control.Button
        confirmButton_7            matlab.ui.control.Button
        confirmButton_6            matlab.ui.control.Button
        confirmButton_5            matlab.ui.control.Button
        confirmButton_4            matlab.ui.control.Button
        confirmButton_3            matlab.ui.control.Button
        confirmButton_2            matlab.ui.control.Button
        confirmButton_1            matlab.ui.control.Button
        Label_18                   matlab.ui.control.Label
        Label_17                   matlab.ui.control.Label
        Label_16                   matlab.ui.control.Label
        Label_15                   matlab.ui.control.Label
        Label_14                   matlab.ui.control.Label
        Label_13                   matlab.ui.control.Label
        Label_12                   matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        Label_10                   matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        SensorVoltageAxes          matlab.ui.control.UIAxes
        PumpPanel                  matlab.ui.container.Panel
        SetLabel                   matlab.ui.control.Label
        StopButton_2               matlab.ui.control.StateButton
        StartButton_2              matlab.ui.control.StateButton
        PumpVoltageAxes            matlab.ui.control.UIAxes
        ValvePanel                 matlab.ui.container.Panel
        StopButton_3               matlab.ui.control.StateButton
        StartButton_3              matlab.ui.control.StateButton
        Label_19                   matlab.ui.control.Label
        ValveVoltageAxes           matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        PIMIMOPanel                matlab.ui.container.Panel
        EditField4                 matlab.ui.control.NumericEditField
        EditField4Label            matlab.ui.control.Label
        KiEditField_3              matlab.ui.control.NumericEditField
        KiEditField_3Label         matlab.ui.control.Label
        KpEditField_2              matlab.ui.control.NumericEditField
        KpEditField_2Label         matlab.ui.control.Label
        KiEditField_2              matlab.ui.control.NumericEditField
        KiEditField_2Label         matlab.ui.control.Label
        PISISOPanel                matlab.ui.container.Panel
        KiEditField                matlab.ui.control.NumericEditField
        KiEditFieldLabel           matlab.ui.control.Label
        KpEditField                matlab.ui.control.NumericEditField
        KpEditFieldLabel           matlab.ui.control.Label
        RLPanel                    matlab.ui.container.Panel
        Label_20                   matlab.ui.control.Label
        MPCPanel                   matlab.ui.container.Panel
        EditField18                matlab.ui.control.NumericEditField
        EditField18Label           matlab.ui.control.Label
        EditField17                matlab.ui.control.NumericEditField
        EditField17Label           matlab.ui.control.Label
        EditField16                matlab.ui.control.NumericEditField
        EditField16Label           matlab.ui.control.Label
        EditField15                matlab.ui.control.NumericEditField
        EditField15Label           matlab.ui.control.Label
        EditField14                matlab.ui.control.NumericEditField
        EditField14Label           matlab.ui.control.Label
        EditField13                matlab.ui.control.NumericEditField
        EditField13Label           matlab.ui.control.Label
        BSMIMOPanel                matlab.ui.container.Panel
        EditField12                matlab.ui.control.NumericEditField
        EditField12Label           matlab.ui.control.Label
        EditField11                matlab.ui.control.NumericEditField
        EditField11Label           matlab.ui.control.Label
        EditField10                matlab.ui.control.NumericEditField
        EditField10Label           matlab.ui.control.Label
        BSSISOPanel                matlab.ui.container.Panel
        EditField9                 matlab.ui.control.NumericEditField
        EditField9Label            matlab.ui.control.Label
        IOMIMOPanel                matlab.ui.container.Panel
        EditField8                 matlab.ui.control.NumericEditField
        EditField8Label            matlab.ui.control.Label
        EditField7                 matlab.ui.control.NumericEditField
        EditField7Label            matlab.ui.control.Label
        EditField6                 matlab.ui.control.NumericEditField
        EditField6Label            matlab.ui.control.Label
        IOSISOPanel                matlab.ui.container.Panel
        EditField5                 matlab.ui.control.NumericEditField
        EditField5Label            matlab.ui.control.Label
        ControllerParametersLabel  matlab.ui.control.Label
        StopButton_4               matlab.ui.control.Button
        StartButton_4              matlab.ui.control.Button
        ReferenceLabel             matlab.ui.control.Label
        T3EditField                matlab.ui.control.NumericEditField
        T3EditFieldLabel           matlab.ui.control.Label
        T2EditField                matlab.ui.control.NumericEditField
        T2EditFieldLabel           matlab.ui.control.Label
        T1EditField                matlab.ui.control.NumericEditField
        T1EditFieldLabel           matlab.ui.control.Label
        ControllerDropDown         matlab.ui.control.DropDown
        ControllerLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
            PumpPlotTimer
            PumpLine1
            PumpLine2
            PumpStartTime
            ValvePlotTimer
            ValveLine1
            ValveLine2
            ValveLine3
            ValveStartTime
            SensorPlotTimer
            SensorLine1
            SensorLine2
            SensorLine3
            SensorStartTime

    end
    
    methods (Access = private)
        
        function recordPoint(app, level, button)
        
            mdl = "Copy_2_of_calibration_sensors";
        
            blockName = sprintf("conflevel%d", level);
            blockPath = mdl + "/" + blockName;
        
            simStatus = get_param(mdl, "SimulationStatus");
        
            if ~strcmp(simStatus, "running")
                uialert(app.UIFigure, ...
                    "Please start Sensor Calibration first.", ...
                    "Simulation not running");
                return
            end
        
            set_param(blockPath, "Value", "1");
            pause(0.2);
            set_param(blockPath, "Value", "0");
        
            button.Text = "Recorded";
            button.Enable = "off";
        
        end

        function updateSensorPlot(app)
        
            mdl = "Copy_2_of_calibration_sensors";
        
            if ~bdIsLoaded(mdl)
                return
            end
        
            if ~strcmp(get_param(mdl, "SimulationStatus"), "running")
                return
            end
        
            try
        
                blk1 = mdl + "/GUI_ADC1";
                blk2 = mdl + "/GUI_ADC2";
                blk3 = mdl + "/GUI_ADC3";
        
                rto1 = get_param(blk1, "RuntimeObject");
                rto2 = get_param(blk2, "RuntimeObject");
                rto3 = get_param(blk3, "RuntimeObject");
        
                adc1 = double(rto1.OutputPort(1).Data);
                adc2 = double(rto2.OutputPort(1).Data);
                adc3 = double(rto3.OutputPort(1).Data);
        
                t = toc(app.SensorStartTime);
        
                addpoints(app.SensorLine1, t, adc1);
                addpoints(app.SensorLine2, t, adc3);
                addpoints(app.SensorLine3, t, adc2);
        
                window = 30;
        
                if t <= window
                    app.SensorVoltageAxes.XLim = [0 window];
                else
                    app.SensorVoltageAxes.XLim = [t-window t];
                end
        
                drawnow limitrate
        
            catch ME
        
                disp("updateSensorPlot ERROR:");
                disp(ME.message);
        
            end
        
        end

        function updatePumpPlot(app)
        
            mdl = "Copy_of_calibration_pumps";
        
            if ~bdIsLoaded(mdl)
                return
            end
        
            if ~strcmp(get_param(mdl, "SimulationStatus"), "running")
                return
            end
        
            try
        
                blk1 = mdl + "/GUI_ADC1";
                blk3 = mdl + "/GUI_ADC3";
        
                rto1 = get_param(blk1, "RuntimeObject");
                rto3 = get_param(blk3, "RuntimeObject");
        
                adc1 = double(rto1.OutputPort(1).Data);
                adc3 = double(rto3.OutputPort(1).Data);
        
                fprintf("adc1 = %.3f V, adc3 = %.3f V\n", ...
                    adc1, adc3);
        
                t = toc(app.PumpStartTime);
        
                addpoints(app.PumpLine1, t, adc1);
                addpoints(app.PumpLine2, t, adc3);
        
                window = 30;
        
                if t <= window
                    app.PumpVoltageAxes.XLim = [0 window];
                else
                    app.PumpVoltageAxes.XLim = [t-window t];
                end
        
                drawnow limitrate
        
            catch ME
        
                disp("updatePumpPlot ERROR:");
                disp(ME.message);
        
            end
        
        end

        function updateValvePlot(app)

            mdl = "Copy_of_calibration_valves";

            if ~bdIsLoaded(mdl)
                return
            end

            if ~strcmp(get_param(mdl, "SimulationStatus"), "running")
                return
            end

            try

                blk1 = mdl + "/GUI_ADC1";
                blk2 = mdl + "/GUI_ADC2";
                blk3 = mdl + "/GUI_ADC3";

                rto1 = get_param(blk1, "RuntimeObject");
                rto2 = get_param(blk2, "RuntimeObject");
                rto3 = get_param(blk3, "RuntimeObject");

                adc1 = double(rto1.OutputPort(1).Data);
                adc2 = double(rto2.OutputPort(1).Data);
                adc3 = double(rto3.OutputPort(1).Data);

                t = toc(app.ValveStartTime);

                addpoints(app.ValveLine1, t, adc1);
                addpoints(app.ValveLine2, t, adc3);
                addpoints(app.ValveLine3, t, adc2);

                window = 30;

                if t <= window
                    app.ValveVoltageAxes.XLim = [0 window];
                else
                    app.ValveVoltageAxes.XLim = [t-window t];
                end

                drawnow limitrate

            catch ME

                disp("updateValvePlot ERROR:");
                disp(ME.message);

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: sensorsMenu
        function sensorsMenuSelected(app, event)
            app.ValvePanel.Visible = "off";
            app.PumpPanel.Visible   = "off";
            app.SensorPanel.Visible  = "on";
        end

        % Menu selected function: pumpsMenu
        function pumpsMenuSelected(app, event)
            app.ValvePanel.Visible = "off";
            app.PumpPanel.Visible   = "on";
            app.SensorPanel.Visible  = "off";
        end

        % Menu selected function: valvesMenu
        function valvesMenuSelected(app, event)
            app.ValvePanel.Visible = "on";
            app.PumpPanel.Visible   = "off";
            app.SensorPanel.Visible  = "off";
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            mdl = "Copy_2_of_calibration_sensors";
        
            try
                load_system(mdl);
        
                set_param(mdl, "StopTime", "inf");
        
                set_param(mdl, ...
                    "SimulationCommand", ...
                    "start");
        
                pause(0.3);
        
                status = get_param(mdl, "SimulationStatus");
        
                if strcmp(status, "running")
        
                    app.StartButton.Text = "Running";
                    app.StartButton.Enable = "off";
        
                    cla(app.SensorVoltageAxes);
        
                    app.SensorLine1 = animatedline( ...
                        app.SensorVoltageAxes, ...
                        "LineWidth", 1.5, ...
                        "Color", [0 0.4470 0.7410], ...
                        "DisplayName", "Tank 1");
        
                    app.SensorLine2 = animatedline( ...
                        app.SensorVoltageAxes, ...
                        "LineWidth", 1.5, ...
                        "Color", [0.8500 0.3250 0.0980], ...
                        "DisplayName", "Tank 2");
        
                    app.SensorLine3 = animatedline( ...
                        app.SensorVoltageAxes, ...
                        "LineWidth", 1.5, ...
                        "Color", [0.4660 0.6740 0.1880], ...
                        "DisplayName", "Tank 3");
        
                    xlabel(app.SensorVoltageAxes, "Time (s)");
                    ylabel(app.SensorVoltageAxes, "Voltage (V)");
        
                    title(app.SensorVoltageAxes, ...
                        "Real-Time Sensor Voltage");
        
                    ylim(app.SensorVoltageAxes, [0 3.3]);
                    xlim(app.SensorVoltageAxes, [0 30]);
        
                    grid(app.SensorVoltageAxes, "on");
        
                    legend(app.SensorVoltageAxes, ...
                        "show", ...
                        "Location", "best");
        
                    app.SensorStartTime = tic;
        
                    if ~isempty(app.SensorPlotTimer) && ...
                            isvalid(app.SensorPlotTimer)
        
                        stop(app.SensorPlotTimer);
                        delete(app.SensorPlotTimer);
        
                    end
        
                    app.SensorPlotTimer = timer( ...
                        "ExecutionMode", "fixedSpacing", ...
                        "Period", 1, ...
                        "TimerFcn", @(~,~) app.updateSensorPlot());
        
                    start(app.SensorPlotTimer);
        
                else
        
                    app.StartButton.Text = "Start";
                    app.StartButton.Enable = "on";
        
                    uialert(app.UIFigure, ...
                        "Model status: " + string(status), ...
                        "Simulation Error");
        
                end
        
            catch ME
        
                app.StartButton.Text = "Start";
                app.StartButton.Enable = "on";
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Start Error");
        
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            mdl = "Copy_2_of_calibration_sensors";
        
            try
        
                if ~isempty(app.SensorPlotTimer) && ...
                        isvalid(app.SensorPlotTimer)
        
                    stop(app.SensorPlotTimer);
                    delete(app.SensorPlotTimer);
                    app.SensorPlotTimer = [];
        
                end
        
                if bdIsLoaded(mdl)
        
                    status = get_param(mdl, "SimulationStatus");
        
                    if strcmp(status, "running")
                        set_param(mdl, ...
                            "SimulationCommand", ...
                            "stop");
                    end
        
                end
        
                app.StartButton.Text = "Start";
                app.StartButton.Enable = "on";
        
            catch ME
        
                app.StartButton.Text = "Start";
                app.StartButton.Enable = "on";
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Sensor Stop Error");
        
            end
        end

        % Button pushed function: confirmButton_1
        function confirmButton_1Pushed(app, event)
            app.recordPoint(1, app.confirmButton_1);
        end

        % Button pushed function: confirmButton_2
        function confirmButton_2Pushed(app, event)
            app.recordPoint(2, app.confirmButton_2);
        end

        % Button pushed function: confirmButton_3
        function confirmButton_3Pushed(app, event)
            app.recordPoint(3, app.confirmButton_3);
        end

        % Button pushed function: confirmButton_4
        function confirmButton_4Pushed(app, event)
            app.recordPoint(4, app.confirmButton_4);
        end

        % Button pushed function: confirmButton_5
        function confirmButton_5Pushed(app, event)
            app.recordPoint(5, app.confirmButton_5);
        end

        % Button pushed function: confirmButton_6
        function confirmButton_6Pushed(app, event)
            app.recordPoint(6, app.confirmButton_6);
        end

        % Button pushed function: confirmButton_7
        function confirmButton_7Pushed(app, event)
            app.recordPoint(7, app.confirmButton_7);
        end

        % Button pushed function: confirmButton_8
        function confirmButton_8Pushed(app, event)
            app.recordPoint(8, app.confirmButton_8);
        end

        % Button pushed function: confirmButton_9
        function confirmButton_9Pushed(app, event)
            app.recordPoint(9, app.confirmButton_9);
        end

        % Value changed function: StartButton_2
        function StartButton_2ValueChanged(app, event)
            mdl = "Copy_of_calibration_pumps";
        
            try
        
                load_system(mdl);
        
                set_param(mdl, "BlockReduction", "off");
        
                set_param(mdl, "StopTime", "inf");
        
                set_param(mdl, ...
                    "SimulationCommand", ...
                    "start");
        
                pause(0.3);
        
                status = get_param(mdl, "SimulationStatus");
        
                if strcmp(status, "running")
        
                    app.StartButton_2.Text = "Running";
                    app.StartButton_2.Enable = "off";
        
                    cla(app.PumpVoltageAxes);
        
                    app.PumpLine1 = animatedline( ...
                        app.PumpVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0 0.4470 0.7410], ...
                        "DisplayName", "Tank 1");
                    
                    app.PumpLine2 = animatedline( ...
                        app.PumpVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0.8500 0.3250 0.0980], ...
                        "DisplayName", "Tank 2");
        
                    xlabel(app.PumpVoltageAxes, "Time (s)");
                    ylabel(app.PumpVoltageAxes, "Voltage (V)");
                    title(app.PumpVoltageAxes, ...
                        "Real-Time Sensor Voltage");
        
                    ylim(app.PumpVoltageAxes, [0 3.3]);
                    xlim(app.PumpVoltageAxes, [0 30]);
        
                    grid(app.PumpVoltageAxes, "on");
        
                    legend(app.PumpVoltageAxes, ...
                        "show", ...
                        "Location", "best");
        
                    app.PumpStartTime = tic;
        
                    if ~isempty(app.PumpPlotTimer) && ...
                            isvalid(app.PumpPlotTimer)
        
                        stop(app.PumpPlotTimer);
                        delete(app.PumpPlotTimer);
                    end
        
                    app.PumpPlotTimer = timer( ...
                        "ExecutionMode", "fixedSpacing", ...
                        "Period", 1, ...
                        "TimerFcn", @(~,~) app.updatePumpPlot());
        
                    start(app.PumpPlotTimer);
        
                else
        
                    app.StartButton_2.Text = "Start";
                    app.StartButton_2.Enable = "on";
        
                    uialert(app.UIFigure, ...
                        "Model status: " + string(status), ...
                        "Pump Calibration Error");
        
                end
        
            catch ME
        
                app.StartButton_2.Text = "Start";
                app.StartButton_2.Enable = "on";
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Pump Start Error");
        
            end
        end

        % Value changed function: StopButton_2
        function StopButton_2ValueChanged(app, event)
            mdl = "Copy_of_calibration_pumps";
        
            try
        
                if ~isempty(app.PumpPlotTimer) && ...
                        isvalid(app.PumpPlotTimer)
        
                    stop(app.PumpPlotTimer);
                    delete(app.PumpPlotTimer);
        
                    app.PumpPlotTimer = [];
        
                end
        
                if bdIsLoaded(mdl)
        
                    status = get_param(mdl, ...
                        "SimulationStatus");
        
                    if ~strcmp(status, "stopped")
        
                        set_param(mdl, ...
                            "SimulationCommand", ...
                            "stop");
        
                    end
        
                end
        
                app.StartButton_2.Text = "Start";
                app.StartButton_2.Enable = "on";
        
            catch ME
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Pump Stop Error");
        
            end

        end

        % Value changed function: StartButton_3
        function StartButton_3ValueChanged(app, event)
            mdl = "Copy_of_calibration_valves";
        
            try
        
                load_system(mdl);
        
                set_param(mdl, "BlockReduction", "off");
        
                set_param(mdl, "StopTime", "inf");
        
                set_param(mdl, ...
                    "SimulationCommand", ...
                    "start");
        
                pause(0.3);
        
                status = get_param(mdl, "SimulationStatus");
        
                if strcmp(status, "running")
        
                    app.StartButton_3.Text = "Running";
                    app.StartButton_3.Enable = "off";
        
                    cla(app.ValveVoltageAxes);
        
                    app.ValveLine1 = animatedline( ...
                        app.ValveVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0 0.4470 0.7410], ...
                        "DisplayName", "Tank 1");
        
                    app.ValveLine2 = animatedline( ...
                        app.ValveVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0.8500 0.3250 0.0980], ...
                        "DisplayName", "Tank 2");
        
                    app.ValveLine3 = animatedline( ...
                        app.ValveVoltageAxes, ...
                        "LineWidth", 1.8, ...
                        "Color", [0.4660 0.6740 0.1880], ...
                        "DisplayName", "Tank 3");
        
                    xlabel(app.ValveVoltageAxes, "Time (s)");
                    ylabel(app.ValveVoltageAxes, "Voltage (V)");
        
                    title(app.ValveVoltageAxes, ...
                        "Real-Time Sensor Voltage");
        
                    ylim(app.ValveVoltageAxes, [0 3.3]);
                    xlim(app.ValveVoltageAxes, [0 30]);
        
                    grid(app.ValveVoltageAxes, "on");
        
                    legend(app.ValveVoltageAxes, ...
                        "show", ...
                        "Location", "best");
        
                    app.ValveStartTime = tic;
        
                    if ~isempty(app.ValvePlotTimer) && ...
                            isvalid(app.ValvePlotTimer)
        
                        stop(app.ValvePlotTimer);
                        delete(app.ValvePlotTimer);
        
                    end
        
                    app.ValvePlotTimer = timer( ...
                        "ExecutionMode", "fixedSpacing", ...
                        "Period", 1, ...
                        "TimerFcn", @(~,~) app.updateValvePlot());
        
                    start(app.ValvePlotTimer);
        
                else
        
                    app.StartButton_3.Text = "Start";
                    app.StartButton_3.Enable = "on";
        
                    uialert(app.UIFigure, ...
                        "Model status: " + string(status), ...
                        "Valve Calibration Error");
        
                end
        
            catch ME
        
                app.StartButton_3.Text = "Start";
                app.StartButton_3.Enable = "on";
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Valve Start Error");
        
            end
        end

        % Value changed function: StopButton_3
        function StopButton_3ValueChanged(app, event)
            mdl = "Copy_of_calibration_valves";
        
            try
        
                if ~isempty(app.ValvePlotTimer) && ...
                        isvalid(app.ValvePlotTimer)
        
                    stop(app.ValvePlotTimer);
                    delete(app.ValvePlotTimer);
        
                    app.ValvePlotTimer = [];
        
                end
        
                if bdIsLoaded(mdl)
        
                    status = get_param(mdl, "SimulationStatus");
        
                    if ~strcmp(status, "stopped")
        
                        set_param(mdl, ...
                            "SimulationCommand", ...
                            "stop");
        
                    end
        
                end
        
                app.StartButton_3.Text = "Start";
                app.StartButton_3.Enable = "on";
        
            catch ME
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Valve Stop Error");
        
            end
        end

        % Value changed function: ControllerDropDown
        function ControllerDropDownValueChanged(app, event)
            app.RLPanel.Visible = "off";
            app.MPCPanel.Visible = "off";
            app.BSMIMOPanel.Visible = "off";
            app.BSSISOPanel.Visible = "off";
            app.IOMIMOPanel.Visible = "off";
            app.IOSISOPanel.Visible = "off";
            app.PIMIMOPanel.Visible = "off";
            app.PISISOPanel.Visible = "off";
        
            controller = app.ControllerDropDown.Value;
        
            switch controller
        
                case "PI_SISO"
                    app.PISISOPanel.Visible = "on";
        
                case "PI_MIMO"
                    app.PIMIMOPanel.Visible = "on";
        
                case "IO_linearization_SISO"
                    app.IOSISOPanel.Visible = "on";
        
                case "IO_linearization_MIMO"
                    app.IOMIMOPanel.Visible = "on";
        
                case "Backstepping_SISO"
                    app.BSSISOPanel.Visible = "on";
        
                case "Backstepping_MIMO"
                    app.BSMIMOPanel.Visible = "on";
        
                case "MPC"
                    app.MPCPanel.Visible = "on";
        
                case "RL"
                    app.RLPanel.Visible = "on";
        
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModeMenu = uimenu(app.UIFigure);
            app.ModeMenu.Text = 'Mode';
            app.CalibrationMenu = uimenu(app.ModeMenu);
            app.CalibrationMenu.Text = 'Calibration';
            app.sensorsMenu = uimenu(app.CalibrationMenu);
            app.sensorsMenu.MenuSelectedFcn = createCallbackFcn(app, @sensorsMenuSelected, true);
            app.sensorsMenu.Text = 'sensors';
            app.pumpsMenu = uimenu(app.CalibrationMenu);
            app.pumpsMenu.MenuSelectedFcn = createCallbackFcn(app, @pumpsMenuSelected, true);
            app.pumpsMenu.Text = 'pumps';
            app.valvesMenu = uimenu(app.CalibrationMenu);
            app.valvesMenu.MenuSelectedFcn = createCallbackFcn(app, @valvesMenuSelected, true);
            app.valvesMenu.Text = 'valves';
            app.ControlMenu = uimenu(app.ModeMenu);
            app.ControlMenu.Text = 'Control';
            app.Panel = uipanel(app.UIFigure);
            app.ControllerLabel = uilabel(app.Panel);
            app.ControllerLabel.Text = 'Controller ';
            app.ControllerDropDown = uidropdown(app.Panel);
            app.ControllerDropDown.Items = {'none', 'PI_SISO', 'PI_MIMO', 'IO_linearization_SISO', 'IO_linearization_MIMO', 'Backstepping_SISO', 'Backstepping_MIMO', 'MPC', 'RL'};
            app.ControllerDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerDropDownValueChanged, true);
            app.ControllerDropDown.Value = 'none';
            app.T1EditFieldLabel = uilabel(app.Panel);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.Panel, 'numeric');
            app.T2EditFieldLabel = uilabel(app.Panel);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.Panel, 'numeric');
            app.T3EditFieldLabel = uilabel(app.Panel);
            app.T3EditFieldLabel.Text = 'T3';
            app.T3EditField = uieditfield(app.Panel, 'numeric');
            app.ReferenceLabel = uilabel(app.Panel);
            app.ReferenceLabel.Text = 'Reference';
            app.StartButton_4 = uibutton(app.Panel, 'push');
            app.StartButton_4.Text = 'Start';
            app.StopButton_4 = uibutton(app.Panel, 'push');
            app.StopButton_4.Text = 'Stop';
            app.ControllerParametersLabel = uilabel(app.Panel);
            app.ControllerParametersLabel.Text = 'Controller Parameters';
            app.IOSISOPanel = uipanel(app.Panel);
            app.IOSISOPanel.Visible = 'off';
            app.EditField5Label = uilabel(app.IOSISOPanel);
            app.EditField5Label.Text = 'Edit Field5';
            app.EditField5 = uieditfield(app.IOSISOPanel, 'numeric');
            app.IOMIMOPanel = uipanel(app.Panel);
            app.IOMIMOPanel.Visible = 'off';
            app.EditField6Label = uilabel(app.IOMIMOPanel);
            app.EditField6Label.Text = 'Edit Field6';
            app.EditField6 = uieditfield(app.IOMIMOPanel, 'numeric');
            app.EditField7Label = uilabel(app.IOMIMOPanel);
            app.EditField7Label.Text = 'Edit Field7';
            app.EditField7 = uieditfield(app.IOMIMOPanel, 'numeric');
            app.EditField8Label = uilabel(app.IOMIMOPanel);
            app.EditField8Label.Text = 'Edit Field8';
            app.EditField8 = uieditfield(app.IOMIMOPanel, 'numeric');
            app.BSSISOPanel = uipanel(app.Panel);
            app.BSSISOPanel.Visible = 'off';
            app.EditField9Label = uilabel(app.BSSISOPanel);
            app.EditField9Label.Text = 'Edit Field9';
            app.EditField9 = uieditfield(app.BSSISOPanel, 'numeric');
            app.BSMIMOPanel = uipanel(app.Panel);
            app.BSMIMOPanel.Visible = 'off';
            app.EditField10Label = uilabel(app.BSMIMOPanel);
            app.EditField10Label.Text = 'Edit Field10';
            app.EditField10 = uieditfield(app.BSMIMOPanel, 'numeric');
            app.EditField11Label = uilabel(app.BSMIMOPanel);
            app.EditField11Label.Text = 'Edit Field11';
            app.EditField11 = uieditfield(app.BSMIMOPanel, 'numeric');
            app.EditField12Label = uilabel(app.BSMIMOPanel);
            app.EditField12Label.Text = 'Edit Field12';
            app.EditField12 = uieditfield(app.BSMIMOPanel, 'numeric');
            app.MPCPanel = uipanel(app.Panel);
            app.MPCPanel.Visible = 'off';
            app.EditField13Label = uilabel(app.MPCPanel);
            app.EditField13Label.Text = 'Edit Field13';
            app.EditField13 = uieditfield(app.MPCPanel, 'numeric');
            app.EditField14Label = uilabel(app.MPCPanel);
            app.EditField14Label.Text = 'Edit Field14';
            app.EditField14 = uieditfield(app.MPCPanel, 'numeric');
            app.EditField15Label = uilabel(app.MPCPanel);
            app.EditField15Label.Text = 'Edit Field15';
            app.EditField15 = uieditfield(app.MPCPanel, 'numeric');
            app.EditField16Label = uilabel(app.MPCPanel);
            app.EditField16Label.Text = 'Edit Field16';
            app.EditField16 = uieditfield(app.MPCPanel, 'numeric');
            app.EditField17Label = uilabel(app.MPCPanel);
            app.EditField17Label.Text = 'Edit Field17';
            app.EditField17 = uieditfield(app.MPCPanel, 'numeric');
            app.EditField18Label = uilabel(app.MPCPanel);
            app.EditField18Label.Text = 'Edit Field18';
            app.EditField18 = uieditfield(app.MPCPanel, 'numeric');
            app.RLPanel = uipanel(app.Panel);
            app.RLPanel.Visible = 'off';
            app.Label_20 = uilabel(app.RLPanel);
            app.Label_20.Text = 'No additional parameters required';
            app.PISISOPanel = uipanel(app.Panel);
            app.PISISOPanel.Visible = 'off';
            app.KpEditFieldLabel = uilabel(app.PISISOPanel);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.PISISOPanel, 'numeric');
            app.KiEditFieldLabel = uilabel(app.PISISOPanel);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.PISISOPanel, 'numeric');
            app.PIMIMOPanel = uipanel(app.Panel);
            app.KiEditField_2Label = uilabel(app.PIMIMOPanel);
            app.KiEditField_2Label.Text = 'Ki';
            app.KiEditField_2 = uieditfield(app.PIMIMOPanel, 'numeric');
            app.KpEditField_2Label = uilabel(app.PIMIMOPanel);
            app.KpEditField_2Label.Text = 'Kp';
            app.KpEditField_2 = uieditfield(app.PIMIMOPanel, 'numeric');
            app.KiEditField_3Label = uilabel(app.PIMIMOPanel);
            app.KiEditField_3Label.Text = 'Ki';
            app.KiEditField_3 = uieditfield(app.PIMIMOPanel, 'numeric');
            app.EditField4Label = uilabel(app.PIMIMOPanel);
            app.EditField4Label.Text = 'Edit Field4';
            app.EditField4 = uieditfield(app.PIMIMOPanel, 'numeric');
            app.ValvePanel = uipanel(app.UIFigure);
            app.ValveVoltageAxes = uiaxes(app.ValvePanel);
            xlabel(app.ValveVoltageAxes, 'Time')
            ylabel(app.ValveVoltageAxes, 'Voltage')
            zlabel(app.ValveVoltageAxes, 'Z')
            app.ValveVoltageAxes.XLim = [0 30];
            app.ValveVoltageAxes.YLim = [0 3.3];
            app.ValveVoltageAxes.XTick = [];
            app.ValveVoltageAxes.YTick = [0 0.5 1 1.5 2 2.5 3 3.3];
            app.ValveVoltageAxes.YTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'; '3.3'};
            app.Label_19 = uilabel(app.ValvePanel);
            app.Label_19.Text = 'Set the water level to 40,30,20 and press start';
            app.StartButton_3 = uibutton(app.ValvePanel, 'state');
            app.StartButton_3.ValueChangedFcn = createCallbackFcn(app, @StartButton_3ValueChanged, true);
            app.StartButton_3.Text = 'Start';
            app.StopButton_3 = uibutton(app.ValvePanel, 'state');
            app.StopButton_3.ValueChangedFcn = createCallbackFcn(app, @StopButton_3ValueChanged, true);
            app.StopButton_3.Text = 'Stop';
            app.PumpPanel = uipanel(app.UIFigure);
            app.PumpVoltageAxes = uiaxes(app.PumpPanel);
            xlabel(app.PumpVoltageAxes, 'Time ')
            ylabel(app.PumpVoltageAxes, 'Voltage ')
            zlabel(app.PumpVoltageAxes, 'Z')
            app.PumpVoltageAxes.XLim = [0 30];
            app.PumpVoltageAxes.YLim = [0 3.3];
            app.PumpVoltageAxes.XTick = [];
            app.PumpVoltageAxes.XTickLabel = '';
            app.PumpVoltageAxes.YTick = [0 0.5 1 1.5 2 2.5 3];
            app.PumpVoltageAxes.YTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'};
            app.StartButton_2 = uibutton(app.PumpPanel, 'state');
            app.StartButton_2.ValueChangedFcn = createCallbackFcn(app, @StartButton_2ValueChanged, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.PumpPanel, 'state');
            app.StopButton_2.ValueChangedFcn = createCallbackFcn(app, @StopButton_2ValueChanged, true);
            app.StopButton_2.Text = 'Stop';
            app.SetLabel = uilabel(app.PumpPanel);
            app.SetLabel.Text = 'Set the water level to 10 10 10 and press Start.';
            app.SensorPanel = uipanel(app.UIFigure);
            app.SensorVoltageAxes = uiaxes(app.SensorPanel);
            xlabel(app.SensorVoltageAxes, 'Time')
            ylabel(app.SensorVoltageAxes, 'Voltage')
            zlabel(app.SensorVoltageAxes, 'Z')
            app.SensorVoltageAxes.XLim = [0 30];
            app.SensorVoltageAxes.YLim = [0 3.3];
            app.SensorVoltageAxes.XTick = [];
            app.SensorVoltageAxes.YTick = [0 0.5 1 1.5 2 2.5 3 3.3];
            app.SensorVoltageAxes.YTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'; '3.3'};
            app.StartButton = uibutton(app.SensorPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label_10 = uilabel(app.SensorPanel);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.SensorPanel);
            app.Label_11.Text = '15';
            app.Label_12 = uilabel(app.SensorPanel);
            app.Label_12.Text = '20';
            app.Label_13 = uilabel(app.SensorPanel);
            app.Label_13.Text = '25';
            app.Label_14 = uilabel(app.SensorPanel);
            app.Label_14.Text = '30';
            app.Label_15 = uilabel(app.SensorPanel);
            app.Label_15.Text = '35';
            app.Label_16 = uilabel(app.SensorPanel);
            app.Label_16.Text = '40';
            app.Label_17 = uilabel(app.SensorPanel);
            app.Label_17.Text = '45';
            app.Label_18 = uilabel(app.SensorPanel);
            app.Label_18.Text = '50';
            app.confirmButton_1 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_1.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_1Pushed, true);
            app.confirmButton_1.Text = 'confirm';
            app.confirmButton_2 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_2.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_2Pushed, true);
            app.confirmButton_2.Text = 'confirm';
            app.confirmButton_3 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_3.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_3Pushed, true);
            app.confirmButton_3.Text = 'confirm';
            app.confirmButton_4 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_4.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_4Pushed, true);
            app.confirmButton_4.Text = 'confirm';
            app.confirmButton_5 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_5.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_5Pushed, true);
            app.confirmButton_5.Text = 'confirm';
            app.confirmButton_6 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_6.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_6Pushed, true);
            app.confirmButton_6.Text = 'confirm';
            app.confirmButton_7 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_7.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_7Pushed, true);
            app.confirmButton_7.Text = 'confirm';
            app.confirmButton_8 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_8.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_8Pushed, true);
            app.confirmButton_8.Text = 'confirm';
            app.confirmButton_9 = uibutton(app.SensorPanel, 'push');
            app.confirmButton_9.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_9Pushed, true);
            app.confirmButton_9.Text = 'confirm';
            app.StopButton = uibutton(app.SensorPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end